classdef OkayCancelDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SICMOkayCancelDialogUIFigure  matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        OkayButton                    matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        caller; % Description
        prop;
        callback;
        labels = {};
        fields = {};
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, prop, callback, fieldDefinition)
            % caller is the app that calls this app
            % prop is the property of the callback where the input should be stored
            % callback is the callback method that should be called after the app is finished
            % fieldDefinition should be a cell containing structs of the following scheme
            % struct.desc: Label displayed in front of the field
            % struct.type: numeric, int, ...
            app.caller = caller;
            app.prop = prop;
            app.callback = callback;
            ypos = app.Panel.Position(2) + app.Panel.Position(4) + 20;
            height= 20;
            rowcount = 0;
            if iscell(fieldDefinition)
                for i = 1:numel(fieldDefinition) % might be possible with cellfun...
                    rowcount = rowcount+1;
                    lab = uilabel(app.SICMOkayCancelDialogUIFigure, 'Text', fieldDefinition{i}.desc);
                    lab.Position = [20, ypos+i*height, 300, height];
                    
                end
            else
                rowcount = 1;
                lab = uilabel(app.SICMOkayCancelDialogUIFigure, 'Text', fieldDefinition.desc);
                lab.Position = [20, ypos+2*height, 300, height];
                if ~strcmp(fieldDefinition.type, 'string')
                    ef = uieditfield(app.SICMOkayCancelDialogUIFigure,'numeric');
                end
                ef.Position = [20, ypos+height/2, 300, height];
                app.fields{end +1} = ef;
            end
            % Change figure height
            app.SICMOkayCancelDialogUIFigure.Position(4) = (2*rowcount+1)*height+ypos;
            
            % Center this figure in the calling app
            % This app might be called by different apps, thus we cannot refer to caller.something
            % we thus loop through fieldnames(caller)
            parentpos = [];
            for fn = fieldnames(app.caller)'
                if isa(app.caller.(fn{1}), 'matlab.ui.Figure')
                    parentpos = app.caller.(fn{1}).Position;
                    break;
                end
            end
            if ~isempty(parentpos)
                 app.SICMOkayCancelDialogUIFigure.Position(1) = ...
                     parentpos(1) + (parentpos(3) - app.SICMOkayCancelDialogUIFigure.Position(3))/2;
                 app.SICMOkayCancelDialogUIFigure.Position(2) = ...
                     parentpos(2) + (parentpos(4) - app.SICMOkayCancelDialogUIFigure.Position(4))/2;
            end
            % Try to catch the focus...
            app.SICMOkayCancelDialogUIFigure.Visible = 'off';
            app.SICMOkayCancelDialogUIFigure.Visible = 'on';
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            tmp = cell(numel(app.fields),1);
            for i = 1:numel(app.fields)
                tmp{i} = app.fields{i}.Value;
            end
            % Pass input to calling app
            app.caller.(app.prop) = tmp;
            app.caller.(app.callback)();
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SICMOkayCancelDialogUIFigure = uifigure;
            app.SICMOkayCancelDialogUIFigure.Name = 'SICM OkayCancelDialog';
            app.Panel = uipanel(app.SICMOkayCancelDialogUIFigure);
            app.OkayButton = uibutton(app.Panel, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.Panel, 'push');
            app.CancelButton.Text = 'Cancel';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OkayCancelDialog(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SICMOkayCancelDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SICMOkayCancelDialogUIFigure)
        end
    end
end